classdef OCR < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure             matlab.ui.Figure
        GridLayout           matlab.ui.container.GridLayout
        LeftPanel            matlab.ui.container.Panel
        BrowsefileButton     matlab.ui.control.Button
        ConverttoTextButton  matlab.ui.control.Button
        TexttospeechButton   matlab.ui.control.Button
        EditField            matlab.ui.control.EditField
        RightPanel           matlab.ui.container.Panel
        Image                matlab.ui.control.Image
        TextArea             matlab.ui.control.TextArea
        PleaseeditthetextincaseofinconsistenciesLabel  matlab.ui.control.Label
    end

    % Properties that correspond to apps with auto-reflow
    properties (Access = private)
        onePanelWidth = 576;
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: BrowsefileButton
        function BrowsefileButtonPushed(app, event)
% By,				
% Ashok Aravind S N – 1BG18IS007	
% Srinivasan R – 1BG18EC110	
% Ramanand Kulkarni – 1BG18ME036

            global path;
            [filename,  pathname] = uigetfile();
            path=strcat(pathname,filename);
            %path = uigetfile();
            app.Image.ImageSource = imread(path);
            app.EditField.Value = path;
            
        end

        % Button pushed function: ConverttoTextButton
        function ConverttoTextButtonPushed(app, event)
            
            %img=app.Image.ImageSource;
            global path;
            colorImage=imread(path);
            %colorImage=img;
            I = rgb2gray(colorImage);
            
            % Detect MSER regions.
            [~, mserConnComp] = detectMSERFeatures(I, 'RegionAreaRange',[200 8000],'ThresholdDelta',4);
            
            % Use regionprops to measure MSER properties
            mserStats = regionprops(mserConnComp, 'BoundingBox', 'Eccentricity', 'Solidity', 'Extent', 'Euler', 'Image');

            % Compute the aspect ratio using bounding box data.
            bbox = vertcat(mserStats.BoundingBox);
            w = bbox(:,3);
            h = bbox(:,4);
            aspectRatio = w./h;

            % Threshold the data to determine which regions to remove. These thresholds
            % may need to be tuned for other images.
            filterIdx = aspectRatio' > 3; 
            filterIdx = filterIdx | [mserStats.Eccentricity] > .995 ;
            filterIdx = filterIdx | [mserStats.Solidity] < .3;
            filterIdx = filterIdx | [mserStats.Extent] < 0.2 | [mserStats.Extent] > 0.9;
            filterIdx = filterIdx | [mserStats.EulerNumber] < -4;

            % Remove regions
            mserStats(filterIdx) = [];

            % Get a binary image of the a region, and pad it to avoid boundary effects
            % during the stroke width computation.
            regionImage = mserStats(6).Image;
            regionImage = padarray(regionImage, [1 1]);

            % Compute the stroke width image.
            distanceImage = bwdist(~regionImage); 
            skeletonImage = bwmorph(regionImage, 'thin', inf);
            
            % Compute the stroke width variation metric 
            strokeWidthValues = distanceImage(skeletonImage);   
            strokeWidthMetric = std(strokeWidthValues)/mean(strokeWidthValues);
            
            % Threshold the stroke width variation metric
            strokeWidthThreshold = 0.4;
            strokeWidthFilterIdx = strokeWidthMetric > strokeWidthThreshold;
            
            % Process the remaining regions
            for j = 1:numel(mserStats)
    
                regionImage = mserStats(j).Image;
                regionImage = padarray(regionImage, [1 1], 0);
    
                distanceImage = bwdist(~regionImage);
                skeletonImage = bwmorph(regionImage, 'thin', inf);
    
                strokeWidthValues = distanceImage(skeletonImage);
    
                strokeWidthMetric = std(strokeWidthValues)/mean(strokeWidthValues);
    
                strokeWidthFilterIdx(j) = strokeWidthMetric > strokeWidthThreshold;
    
            end

            % Remove regions based on the stroke width variation
            mserStats(strokeWidthFilterIdx) = [];
            
            % Get bounding boxes for all the regions
            bboxes = vertcat(mserStats.BoundingBox);

            % Convert from the [x y width height] bounding box format to the [xmin ymin
            % xmax ymax] format for convenience.
            xmin = bboxes(:,1);
            ymin = bboxes(:,2);
            xmax = xmin + bboxes(:,3) - 1;
            ymax = ymin + bboxes(:,4) - 1;

            % Expand the bounding boxes by a small amount.
            expansionAmount = 0.02;
            xmin = (1-expansionAmount) * xmin;
            ymin = (1-expansionAmount) * ymin;
            xmax = (1+expansionAmount) * xmax;
            ymax = (1+expansionAmount) * ymax;

            % Clip the bounding boxes to be within the image bounds
            xmin = max(xmin, 1);
            ymin = max(ymin, 1);
            xmax = min(xmax, size(I,2));
            ymax = min(ymax, size(I,1));
            
            % Compute the overlap ratio
            expandedBBoxes = [xmin ymin xmax-xmin+1 ymax-ymin+1];
            
            overlapRatio = bboxOverlapRatio(expandedBBoxes, expandedBBoxes);

            % Set the overlap ratio between a bounding box and itself to zero to
            % simplify the graph representation.
            n = size(overlapRatio,1); 
            overlapRatio(1:n+1:n^2) = 0;

            % Create the graph
            g = graph(overlapRatio);

            % Find the connected text regions within the graph
            componentIndices = conncomp(g);
            
            % Merge the boxes based on the minimum and maximum dimensions.
            xmin = accumarray(componentIndices', xmin, [], @min);
            ymin = accumarray(componentIndices', ymin, [], @min);
            xmax = accumarray(componentIndices', xmax, [], @max);
            ymax = accumarray(componentIndices', ymax, [], @max);

            % Compose the merged bounding boxes using the [x y width height] format.
            textBBoxes = [xmin ymin xmax-xmin+1 ymax-ymin+1];
            
            % Remove bounding boxes that only contain one text region
            numRegionsInGroup = histcounts(componentIndices);
            textBBoxes(numRegionsInGroup == 1, :) = [];
                      
            % Display text
            ocrtxt = ocr(I, textBBoxes);
            global sentence;
            sentence = strcat(ocrtxt.Text);
            app.TextArea.Value = sentence;
            
        end

        % Button pushed function: TexttospeechButton
        function TexttospeechButtonPushed(app, event)
            
            global sentence;
            sentence = app.TextArea.Value;
            try
                NET.addAssembly('System.Speech');
                Speaker = System.Speech.Synthesis.SpeechSynthesizer;
                if ~isa(sentence,'cell')
                    sentence = {sentence};
                end
                for n=1:length(sentence)
                        Speaker.Speak (sentence{n});
                end
            end
            
        end

        % Value changed function: EditField
        function EditFieldValueChanged(app, event)
            
            value = app.EditField.Value;
            app.Image.ImageSource= imread(value);
            
        end

        % Changes arrangement of the app based on UIFigure width
        function updateAppLayout(app, event)
            currentFigureWidth = app.UIFigure.Position(3);
            if(currentFigureWidth <= app.onePanelWidth)
                % Change to a 2x1 grid
                app.GridLayout.RowHeight = {556, 556};
                app.GridLayout.ColumnWidth = {'1x'};
                app.RightPanel.Layout.Row = 2;
                app.RightPanel.Layout.Column = 1;
            else
                % Change to a 1x2 grid
                app.GridLayout.RowHeight = {'1x'};
                app.GridLayout.ColumnWidth = {283, '1x'};
                app.RightPanel.Layout.Row = 1;
                app.RightPanel.Layout.Column = 2;
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.SizeChangedFcn = createCallbackFcn(app, @updateAppLayout, true);
            app.GridLayout = uigridlayout(app.UIFigure);
            app.LeftPanel = uipanel(app.GridLayout);
            app.BrowsefileButton = uibutton(app.LeftPanel, 'push');
            app.BrowsefileButton.ButtonPushedFcn = createCallbackFcn(app, @BrowsefileButtonPushed, true);
            app.BrowsefileButton.Text = 'Browse file';
            app.ConverttoTextButton = uibutton(app.LeftPanel, 'push');
            app.ConverttoTextButton.ButtonPushedFcn = createCallbackFcn(app, @ConverttoTextButtonPushed, true);
            app.ConverttoTextButton.Text = 'Convert to Text';
            app.TexttospeechButton = uibutton(app.LeftPanel, 'push');
            app.TexttospeechButton.ButtonPushedFcn = createCallbackFcn(app, @TexttospeechButtonPushed, true);
            app.TexttospeechButton.Text = 'Text to speech';
            app.EditField = uieditfield(app.LeftPanel, 'text');
            app.EditField.ValueChangedFcn = createCallbackFcn(app, @EditFieldValueChanged, true);
            app.RightPanel = uipanel(app.GridLayout);
            app.Image = uiimage(app.RightPanel);
            app.TextArea = uitextarea(app.RightPanel);
            app.PleaseeditthetextincaseofinconsistenciesLabel = uilabel(app.RightPanel);
            app.PleaseeditthetextincaseofinconsistenciesLabel.Text = {'Please edit the text in case of inconsistencies'; ''};
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = OCR

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end